classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel_3                       matlab.ui.container.Panel
        engasueduegLabel              matlab.ui.control.Label
        HybridControlProjectLabel     matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        SystemResponseTab             matlab.ui.container.Tab
        Panel_5                       matlab.ui.container.Panel
        RotaryInvertedpendulumLabel   matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        SystemParametersTab           matlab.ui.container.Tab
        ControlEffortSignalandDisturbancePanel  matlab.ui.container.Panel
        DisturbanceSlider             matlab.ui.control.Slider
        DisturbanceSliderLabel        matlab.ui.control.Label
        ctrl_effort                   matlab.ui.control.UIAxes
        MotorParameterPanel           matlab.ui.container.Panel
        MotorDampingDe003Label        matlab.ui.control.Label
        GearRatio1Label               matlab.ui.control.Label
        MotorResRm98332Label          matlab.ui.control.Label
        BackEMFConstkb025Label        matlab.ui.control.Label
        TorqueConstkt10539Label       matlab.ui.control.Label
        EquivalentInertiaJm78e4Label  matlab.ui.control.Label
        SystemParameterPanel          matlab.ui.container.Panel
        PendulumDamping0Label         matlab.ui.control.Label
        PendulumMassm113gmLabel       matlab.ui.control.Label
        centerofmassL1675mmLabel      matlab.ui.control.Label
        ArmLengthLo70mmLabel          matlab.ui.control.Label
        PendulumInertiaJp2e5Label     matlab.ui.control.Label
        ArmInertiaJa0Label            matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        SensitivityTab                matlab.ui.container.Tab
        Image3_2                      matlab.ui.control.Image
        Image3                        matlab.ui.control.Image
        UIAxes8                       matlab.ui.control.UIAxes
        UIAxes7                       matlab.ui.control.UIAxes
        UIAxes6                       matlab.ui.control.UIAxes
        UIAxes5                       matlab.ui.control.UIAxes
        Panel_4                       matlab.ui.container.Panel
        Image                         matlab.ui.control.Image
        SystemIndicatorsPanel         matlab.ui.container.Panel
        Panel_6                       matlab.ui.container.Panel
        StopTimeEditField             matlab.ui.control.NumericEditField
        StopTimeEditFieldLabel        matlab.ui.control.Label
        StopSimulationButton          matlab.ui.control.Button
        SetParametersButton           matlab.ui.control.Button
        PendulumBalancingLabel        matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        PendulumSpeedrpmGauge         matlab.ui.control.Gauge
        PendulumSpeedrpmGaugeLabel    matlab.ui.control.Label
        MotorSpeedrpmGauge            matlab.ui.control.Gauge
        MotorSpeedrpmGaugeLabel       matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
    end

    
    properties (Access = private)
        LoopFlag=true; % Description
        handle;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure);
            s=tf('s');
            tf1_i=(-8.515*s^2-3.933e-13*s+939.7)/(s*4+276.5*s^3+5478*s^2+2.821e04*s+93.9);
            tf2_i=(-6.623*s^2-5.452e-05*s-1.056e-15)/(s^4+276.5*s^3+5478*s^2+2.821e04*s+93.9);
            tf1_d=(8.515*s^2+3.933e-13*s-939.7)/(s*4+276.5*s^3+5478*s^2+2.821e04*s+93.9);
            tf2_d=(6.623*s^2+5.452e-05*s+1.056e-15)/(s^4+276.5*s^3+5478*s^2+2.821e04*s+93.9);
            tf1_up=tf1_d/tf1_i;
            tf2_up=tf2_d/tf2_i;
            plot(app.UIAxes7,step(tf1_up)); %Theta/Input
            plot(app.UIAxes8,step(tf2_up)); %Alpha/Input
            plot(app.UIAxes5,step(tf1_d)); %Theta/Disturbance
            plot(app.UIAxes6,step(tf2_d)); %Alpha/Disturbance
        end

        % Button pushed function: SetParametersButton
        function SetParametersButtonPushed(app, event)
            open_system('LQR_MODEL');
            % set all variables of the system:
            assignin('base', 'motor_j', 0.0078);
            assignin('base', 'motor_d', 0.0312);
            assignin('base', 'motor_r', 9.8332);
            assignin('base', 'motor_kt', 1.0539);
            assignin('base', 'motor_kb', 0.2478);
            assignin('base', 'motor_eff', 0.69);
            assignin('base', 'motor_gear_eff', 0.9);
            assignin('base', 'motor_gear_k', 120);
            assignin('base', 'arm_l', 0.07);
            assignin('base', 'pendulum_l', 0.0675);
            assignin('base', 'pendulum_D', 0.004);
            assignin('base', 'pendulum_rho', 7800);
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            cla(app.ctrl_effort);
            %cla(app.MotorSpeedrpmGauge);
            %cla(app.PendulumSpeedrpmGauge);
            
            set_param('LQR_MODEL', 'SimulationMode', 'external');
            set_param('LQR_MODEL', 'SimulationCommand', 'connect');
            set_param('LQR_MODEL', 'SimulationCommand', 'start');
            
            %sim('LQR_MODEL');
            app.handle=getSimulinkBlockHandle('LQR_MODEL/Disturbance');
            app.LoopFlag=true;
            while app.LoopFlag
                run('update_app_designer.m');
                pause(1);
            end   
            

        end

        % Button pushed function: StopSimulationButton
        function StopSimulationButtonPushed(app, event)
            %set_param('LQR_MODEL', 'SimulationCommand', 'stop');
            app.LoopFlag=false;
            set_param('LQR_MODEL', 'SimulationCommand', 'stop');
        end

        % Value changing function: DisturbanceSlider
        function DisturbanceSliderValueChanging(app, event)
            changingValue = event.Value;
            set_param(app.handle,'Value',num2str(changingValue));
            %set_param('LQR_MODEL/Disturbance','Value',changingValue);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.SystemIndicatorsPanel = uipanel(app.UIFigure);
            app.SystemIndicatorsPanel.Title = 'System Indicators';
            app.MotorSpeedrpmGaugeLabel = uilabel(app.SystemIndicatorsPanel);
            app.MotorSpeedrpmGaugeLabel.Text = {'Motor Speed'; '(rpm)'};
            app.MotorSpeedrpmGauge = uigauge(app.SystemIndicatorsPanel, 'circular');
            app.MotorSpeedrpmGauge.Limits = [0 200];
            app.MotorSpeedrpmGauge.Tag = 'Out_6';
            app.PendulumSpeedrpmGaugeLabel = uilabel(app.SystemIndicatorsPanel);
            app.PendulumSpeedrpmGaugeLabel.Text = {'Pendulum Speed'; '(rpm)'};
            app.PendulumSpeedrpmGauge = uigauge(app.SystemIndicatorsPanel, 'circular');
            app.PendulumSpeedrpmGauge.Limits = [0 50];
            app.PendulumSpeedrpmGauge.Tag = 'Out_7';
            app.Panel_6 = uipanel(app.SystemIndicatorsPanel);
            app.Lamp = uilamp(app.Panel_6);
            app.PendulumBalancingLabel = uilabel(app.Panel_6);
            app.PendulumBalancingLabel.Text = 'Pendulum Balancing';
            app.SetParametersButton = uibutton(app.Panel_6, 'push');
            app.SetParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetParametersButtonPushed, true);
            app.SetParametersButton.Text = 'Set Parameters';
            app.StopSimulationButton = uibutton(app.Panel_6, 'push');
            app.StopSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StopSimulationButtonPushed, true);
            app.StopSimulationButton.Text = 'Stop Simulation';
            app.StopTimeEditFieldLabel = uilabel(app.Panel_6);
            app.StopTimeEditFieldLabel.Text = 'Stop Time';
            app.StopTimeEditField = uieditfield(app.Panel_6, 'numeric');
            app.StopTimeEditField.Value = Inf;
            app.Panel_4 = uipanel(app.UIFigure);
            app.Image = uiimage(app.Panel_4);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'asunews_97_img.png');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SystemResponseTab = uitab(app.TabGroup);
            app.SystemResponseTab.Title = 'System Response';
            app.UIAxes2 = uiaxes(app.SystemResponseTab);
            title(app.UIAxes2, 'Angle Rate of change of motor')
            xlabel(app.UIAxes2, 'time (sec)')
            ylabel(app.UIAxes2, 'theta-dot-o (rpm)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Tag = 'Out_2';
            app.UIAxes4 = uiaxes(app.SystemResponseTab);
            title(app.UIAxes4, 'Angle Rate of change of pendulum')
            xlabel(app.UIAxes4, 'time (sec)')
            ylabel(app.UIAxes4, 'theta-dot-1 (rpm)')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.Tag = 'Out_4';
            app.UIAxes3 = uiaxes(app.SystemResponseTab);
            title(app.UIAxes3, 'Angle of Pendulum')
            xlabel(app.UIAxes3, 'time (sec)')
            ylabel(app.UIAxes3, 'theta-1 (deg)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.Tag = 'Out_3';
            app.UIAxes = uiaxes(app.SystemResponseTab);
            title(app.UIAxes, 'Angle of Motor')
            xlabel(app.UIAxes, 'time (sec)')
            ylabel(app.UIAxes, 'theta-o (deg)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Tag = 'Out_1';
            app.Panel_5 = uipanel(app.SystemResponseTab);
            app.RotaryInvertedpendulumLabel = uilabel(app.Panel_5);
            app.RotaryInvertedpendulumLabel.Text = 'Rotary Inverted pendulum';
            app.SystemParametersTab = uitab(app.TabGroup);
            app.SystemParametersTab.Title = 'System Parameters';
            app.Image2 = uiimage(app.SystemParametersTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'RIP.png');
            app.SystemParameterPanel = uipanel(app.SystemParametersTab);
            app.SystemParameterPanel.Title = 'System Parameter';
            app.ArmInertiaJa0Label = uilabel(app.SystemParameterPanel);
            app.ArmInertiaJa0Label.Text = 'Arm Inertia (Ja): 0';
            app.PendulumInertiaJp2e5Label = uilabel(app.SystemParameterPanel);
            app.PendulumInertiaJp2e5Label.Text = 'Pendulum Inertia (Jp): 2e-5';
            app.ArmLengthLo70mmLabel = uilabel(app.SystemParameterPanel);
            app.ArmLengthLo70mmLabel.Text = 'Arm Length (Lo): 70 mm';
            app.centerofmassL1675mmLabel = uilabel(app.SystemParameterPanel);
            app.centerofmassL1675mmLabel.Text = 'center of mass (L1): 67.5 mm';
            app.PendulumMassm113gmLabel = uilabel(app.SystemParameterPanel);
            app.PendulumMassm113gmLabel.Text = 'Pendulum Mass (m1): 13 gm';
            app.PendulumDamping0Label = uilabel(app.SystemParameterPanel);
            app.PendulumDamping0Label.Text = 'Pendulum Damping: 0';
            app.MotorParameterPanel = uipanel(app.SystemParametersTab);
            app.MotorParameterPanel.Title = 'Motor Parameter';
            app.EquivalentInertiaJm78e4Label = uilabel(app.MotorParameterPanel);
            app.EquivalentInertiaJm78e4Label.Text = 'Equivalent Inertia (Jm): 78e-4';
            app.TorqueConstkt10539Label = uilabel(app.MotorParameterPanel);
            app.TorqueConstkt10539Label.Text = 'Torque Const (kt): 1.0539';
            app.BackEMFConstkb025Label = uilabel(app.MotorParameterPanel);
            app.BackEMFConstkb025Label.Text = 'Back EMF Const (kb): 0.25';
            app.MotorResRm98332Label = uilabel(app.MotorParameterPanel);
            app.MotorResRm98332Label.Text = 'Motor Res (Rm): 9.8332';
            app.GearRatio1Label = uilabel(app.MotorParameterPanel);
            app.GearRatio1Label.Text = 'Gear Ratio: 1';
            app.MotorDampingDe003Label = uilabel(app.MotorParameterPanel);
            app.MotorDampingDe003Label.Text = 'Motor Damping (De): 0.03';
            app.ControlEffortSignalandDisturbancePanel = uipanel(app.SystemParametersTab);
            app.ControlEffortSignalandDisturbancePanel.Title = 'Control Effort Signal and Disturbance';
            app.ctrl_effort = uiaxes(app.ControlEffortSignalandDisturbancePanel);
            title(app.ctrl_effort, 'Control Effort')
            xlabel(app.ctrl_effort, 'time (sec)')
            ylabel(app.ctrl_effort, 'u')
            zlabel(app.ctrl_effort, 'Z')
            app.ctrl_effort.YLim = [0 255];
            app.ctrl_effort.Tag = 'Out_5';
            app.DisturbanceSliderLabel = uilabel(app.ControlEffortSignalandDisturbancePanel);
            app.DisturbanceSliderLabel.Text = 'Disturbance';
            app.DisturbanceSlider = uislider(app.ControlEffortSignalandDisturbancePanel);
            app.DisturbanceSlider.Limits = [0 127];
            app.DisturbanceSlider.MajorTicks = [0 64 127];
            app.DisturbanceSlider.MajorTickLabels = {'0', '64', '127'};
            app.DisturbanceSlider.ValueChangingFcn = createCallbackFcn(app, @DisturbanceSliderValueChanging, true);
            app.DisturbanceSlider.Tag = 'DIST';
            app.SensitivityTab = uitab(app.TabGroup);
            app.SensitivityTab.Title = 'Sensitivity';
            app.UIAxes5 = uiaxes(app.SensitivityTab);
            title(app.UIAxes5, 'Syp Theta')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.SensitivityTab);
            title(app.UIAxes6, 'Syp Alpha')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.SensitivityTab);
            title(app.UIAxes7, 'Sup Theta')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes8 = uiaxes(app.SensitivityTab);
            title(app.UIAxes8, 'Sup Alpha')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.Image3 = uiimage(app.SensitivityTab);
            app.Image3.ImageSource = 'D:\MatLab\Hybrid_Project\SYS\Syp_t.JPG';
            app.Image3_2 = uiimage(app.SensitivityTab);
            app.Image3_2.ImageSource = 'D:\MatLab\Hybrid_Project\SYS\Syp_a.JPG';
            app.Panel_3 = uipanel(app.UIFigure);
            app.HybridControlProjectLabel = uilabel(app.Panel_3);
            app.HybridControlProjectLabel.Text = 'Hybrid Control Project';
            app.engasueduegLabel = uilabel(app.Panel_3);
            app.engasueduegLabel.Text = 'eng.asu.edu.eg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end